classdef Atrapamiento < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        InputTab                  matlab.ui.container.Tab
        UITable                   matlab.ui.control.Table
        Panel                     matlab.ui.container.Panel
        BTN_FiltradoDownload      matlab.ui.control.Button
        DiadeAtrapamientoFinal    matlab.ui.control.DatePicker
        DatePickerLabel_2         matlab.ui.control.Label
        DiadeAtrapamiento         matlab.ui.control.DatePicker
        DatePickerLabel           matlab.ui.control.Label
        ColumnListBox             matlab.ui.control.ListBox
        ListBoxLabel              matlab.ui.control.Label
        BTN_FiltradoAtrapamiento  matlab.ui.control.Button
        DD_Hoja                   matlab.ui.control.DropDown
        DropDownLabel             matlab.ui.control.Label
        BTN_Cargar                matlab.ui.control.Button
        SeleccionararchivoLabel   matlab.ui.control.Label
        CargadedatosLabel         matlab.ui.control.Label
        OutputTab                 matlab.ui.container.Tab
        Panel2                    matlab.ui.container.Panel
        DescargararchivoLabel     matlab.ui.control.Label
        DescargarxlsxButton       matlab.ui.control.Button
        RESULTADOLabel            matlab.ui.control.Label
        MecanismodeatrapamientoidentificadoLabel  matlab.ui.control.Label
        SalidadedatosLabel        matlab.ui.control.Label
        Image                     matlab.ui.control.Image
    end

    
    properties (Access = public)
        ExcelFilePath   % Ruta completa del archivo Excel seleccionado
        SheetNames      % Lista de hojas del archivo Excel
        FullData        % Almacena los datos completos de la hoja seleccionada
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BTN_Cargar
        function BTN_CargarPushed(app, event)
            % Seleccionar el archivo de Excel
            [file, path] = uigetfile('*.xlsx', 'Seleccione un archivo de Excel');
            if isequal(file, 0)
                % Si el usuario cancela la selección, se sale de la función
                return;
            end
            
            % Construir la ruta completa del archivo seleccionado
            fullPath = fullfile(path, file);
            
            % Cargar la lista de hojas del archivo de Excel
            [~, sheetNames] = xlsfinfo(fullPath);
            
            % Guardar la ruta del archivo y las hojas en la aplicación
            app.ExcelFilePath = fullPath;
            app.SheetNames = sheetNames;
            
            % Actualizar el DropDown DD_Hoja con las hojas del archivo
            app.DD_Hoja.Items = sheetNames;
            
            % Leer la primera hoja para obtener los nombres de las columnas y muestra una muestra de datos
            if ~isempty(sheetNames)
                dataPreview = readtable(fullPath, 'Sheet', sheetNames{1}, 'ReadVariableNames', true);
                
                % Asignar los nombres de las columnas a la UITable
                app.UITable.ColumnName = dataPreview.Properties.VariableNames;
                
                % Mostrar los primeros datos en la UITable para verificar la estructura
                app.UITable.Data = dataPreview;
            end
        end

        % Value changed function: DD_Hoja
        function DD_HojaValueChanged(app, event)
            % Verificar que se haya seleccionado un archivo y una hoja
            if ~isempty(app.ExcelFilePath) && ~isempty(app.DD_Hoja.Value)
                % Obtener el nombre de la hoja seleccionada
                selectedSheet = app.DD_Hoja.Value;
                
                % Leer los datos completos de la hoja seleccionada
                data = readtable(app.ExcelFilePath, 'Sheet', selectedSheet, 'ReadVariableNames', true);
                
                % Guardar los datos completos para referencia
                app.FullData = data;
                
                % Asegurar que los nombres de las columnas en la UITable sean correctos
                app.UITable.ColumnName = data.Properties.VariableNames;
                
                % Llenar el ListBox con los nombres de las columnas
                app.ColumnListBox.Items = data.Properties.VariableNames;
                
                % Inicialmente muestra todas las columnas en la UITable
                app.UITable.Data = data;
            end
        end

        % Button pushed function: BTN_FiltradoAtrapamiento
        function BTN_FiltradoAtrapamientoButtonPushed(app, event)
            % Obtener las fechas seleccionadas en los DatePickers
                fechaInicio = app.DiadeAtrapamiento.Value;
                fechaFin = app.DiadeAtrapamientoFinal.Value;
                
                % Verificar que el rango de fechas sea válido
                if fechaInicio > fechaFin
                    uialert(app.UIFigure, 'La fecha de inicio no puede ser mayor que la fecha final.', 'Error');
                    return;
                end
                
                % Verificar si hay columnas seleccionadas en el ListBox
                selectedColumns = app.ColumnListBox.Value;
                if isempty(selectedColumns)
                    uialert(app.UIFigure, 'Por favor, seleccione al menos una columna en el ListBox.', 'Advertencia');
                    return;
                end
            
                % Verificar si la columna de fecha existe en los datos completos
                dateColumnNames = {'FECHA', 'Fecha', 'Date', 'fecha', 'date'};
                dateColumn = intersect(dateColumnNames, app.FullData.Properties.VariableNames);
                
                if isempty(dateColumn)
                    uialert(app.UIFigure, 'No se encontró una columna de fecha en los datos cargados.', 'Error');
                    return;
                end
            
                % Convertir la columna de fecha al tipo datetime para realizar el filtrado
                app.FullData.(dateColumn{1}) = datetime(app.FullData.(dateColumn{1}));
            
                % Filtrar los datos completos por el rango de fechas seleccionado
                filteredDataByDate = app.FullData(app.FullData.(dateColumn{1}) >= fechaInicio & app.FullData.(dateColumn{1}) <= fechaFin, :);
            
                % Filtrar el resultado por las columnas seleccionadas en el ListBox
                filteredData = filteredDataByDate(:, selectedColumns);
            
                % Actualizar la UITable con los datos filtrados
                app.UITable.ColumnName = selectedColumns;
                app.UITable.Data = filteredData;
        end

        % Value changed function: ColumnListBox
        function ColumnListBoxValueChanged(app, event)
            % Obtener las columnas seleccionadas en el ListBox
            selectedColumns = app.ColumnListBox.Value;
            
            % Verificar si se ha seleccionado al menos una columna
            if isempty(selectedColumns)
                % Si no hay columnas seleccionadas, no muestra nada en la UITable
                app.UITable.Data = [];
                app.UITable.ColumnName = {};
            else
                % Filtrar los datos completos usando las columnas seleccionadas
                filteredData = app.FullData(:, selectedColumns);
                
                % Actualizar los nombres de las columnas en la UITable
                app.UITable.ColumnName = selectedColumns;
                
                % Mostrar los datos filtrados en la UITable
                app.UITable.Data = filteredData;
            end
        end

        % Button pushed function: BTN_FiltradoDownload
        function BTN_FiltradoDownloadButtonPushed(app, event)
            % Verificar si hay datos en la UITable
            if isempty(app.UITable.Data)
                uialert(app.UIFigure, 'No hay datos filtrados para descargar. Por favor, aplique un filtro primero.', 'Advertencia');
                return;
            end
            
            % Abrir un cuadro de diálogo para que el usuario seleccione la ubicación y el nombre del archivo
            [file, path] = uiputfile('*.xlsx', 'Guardar archivo filtrado');
            
            % Verificar si el usuario seleccionó un nombre y ubicación para el archivo
            if isequal(file, 0) || isequal(path, 0)
                % El usuario canceló la operación
                return;
            end
            
            % Generar la ruta completa del archivo
            fullFilePath = fullfile(path, file);
            
            % Obtener los datos y nombres de columnas de la UITable
            data = app.UITable.Data;
            columnNames = app.UITable.ColumnName;
            
            % Verificar si los datos ya están en formato de tabla
            if istable(data)
                dataTable = data;  % Si ya es una tabla, usar directamente
            else
                % Si no es una tabla, convertir a cell array y luego a tabla
                if ~iscell(data)
                    data = num2cell(data);
                end
                dataTable = cell2table(data, 'VariableNames', columnNames);
            end
            
            try
                % Guardar los datos en un archivo Excel
                writetable(dataTable, fullFilePath);
                
                % Mostrar mensaje de éxito
                uialert(app.UIFigure, 'Archivo guardado con éxito.', 'Éxito');
            catch ME
                % Mostrar mensaje de error en caso de falla
                uialert(app.UIFigure, ['Error al guardar el archivo: ' ME.message], 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.Panel = uipanel(app.InputTab);
            app.Panel.Title = 'Panel';
            app.CargadedatosLabel = uilabel(app.Panel);
            app.CargadedatosLabel.Text = 'Carga de datos';
            app.SeleccionararchivoLabel = uilabel(app.Panel);
            app.SeleccionararchivoLabel.Text = 'Seleccionar archivo';
            app.BTN_Cargar = uibutton(app.Panel, 'push');
            app.BTN_Cargar.ButtonPushedFcn = createCallbackFcn(app, @BTN_CargarPushed, true);
            app.BTN_Cargar.Text = 'Cargar';
            app.DropDownLabel = uilabel(app.Panel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DD_Hoja = uidropdown(app.Panel);
            app.DD_Hoja.ValueChangedFcn = createCallbackFcn(app, @DD_HojaValueChanged, true);
            app.BTN_FiltradoAtrapamiento = uibutton(app.Panel, 'push');
            app.BTN_FiltradoAtrapamiento.ButtonPushedFcn = createCallbackFcn(app, @BTN_FiltradoAtrapamientoButtonPushed, true);
            app.BTN_FiltradoAtrapamiento.Text = 'Aplicar Filtrado';
            app.ListBoxLabel = uilabel(app.Panel);
            app.ListBoxLabel.Text = 'List Box';
            app.ColumnListBox = uilistbox(app.Panel);
            app.ColumnListBox.Multiselect = 'on';
            app.ColumnListBox.ValueChangedFcn = createCallbackFcn(app, @ColumnListBoxValueChanged, true);
            app.ColumnListBox.Value = {'Item 1'};
            app.DatePickerLabel = uilabel(app.Panel);
            app.DatePickerLabel.Text = 'Date Picker';
            app.DiadeAtrapamiento = uidatepicker(app.Panel);
            app.DatePickerLabel_2 = uilabel(app.Panel);
            app.DatePickerLabel_2.Text = 'Date Picker';
            app.DiadeAtrapamientoFinal = uidatepicker(app.Panel);
            app.BTN_FiltradoDownload = uibutton(app.Panel, 'push');
            app.BTN_FiltradoDownload.ButtonPushedFcn = createCallbackFcn(app, @BTN_FiltradoDownloadButtonPushed, true);
            app.BTN_FiltradoDownload.Text = 'Descargar Filtrado';
            app.UITable = uitable(app.InputTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.Panel2 = uipanel(app.OutputTab);
            app.Panel2.Title = 'Panel2';
            app.Image = uiimage(app.Panel2);
            app.SalidadedatosLabel = uilabel(app.Panel2);
            app.SalidadedatosLabel.Text = 'Salida de datos';
            app.MecanismodeatrapamientoidentificadoLabel = uilabel(app.Panel2);
            app.MecanismodeatrapamientoidentificadoLabel.Text = 'Mecanismo de atrapamiento identificado:';
            app.RESULTADOLabel = uilabel(app.Panel2);
            app.RESULTADOLabel.Text = 'RESULTADO';
            app.DescargarxlsxButton = uibutton(app.Panel2, 'push');
            app.DescargarxlsxButton.Text = 'Descargar .xlsx';
            app.DescargararchivoLabel = uilabel(app.Panel2);
            app.DescargararchivoLabel.Text = 'Descargar archivo:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Atrapamiento

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end